classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea              matlab.ui.control.TextArea
        convolution           matlab.ui.control.Button
        mean                  matlab.ui.control.Button
        multiple              matlab.ui.control.Button
        add                   matlab.ui.control.Button
        chirpButton           matlab.ui.control.Button
        step                  matlab.ui.control.Button
        square1               matlab.ui.control.Button
        cosButton             matlab.ui.control.Button
        sinButton             matlab.ui.control.Button
        Label_3               matlab.ui.control.Label
        Label_2               matlab.ui.control.Label
        StatusLabel           matlab.ui.control.TextArea
        Label                 matlab.ui.control.Label
        PlayButton            matlab.ui.control.Button
        StartRecordingButton  matlab.ui.control.Button
        signalPanel           matlab.ui.container.Panel
        Panel_2               matlab.ui.container.Panel
        WaveAxes              matlab.ui.control.UIAxes
        RecordingAxes_left    matlab.ui.control.UIAxes
        RecordingAxes_right   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs = 16000;               % 采样频率
        nBits = 8;                % 采样位数
        duration = 5;             % 录音时长
        y                         % 录音对象
        myRecording               % 录音数据
        freq = 1;
        amp = 1;
        t_start = 0;
        t_end = 1;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            %低通滤波器
            [b, a] = butter(4, 4000/(app.fs/2), 'low');

            % 显示“请等待录音完成”
            app.StatusLabel.Value = '请等待录音完成，录音时间5s';
            
            % 创建录音对象并开始录制
            app.y = audiorecorder(app.fs, app.nBits, 2);
            recordblocking(app.y, app.duration);  % 录制时长
            
            % 获取录音数据
            app.myRecording = getaudiodata(app.y);
            
            % 对音频数据进行滤波
            app.myRecording(:, 1) = filter(b, a, app.myRecording(:, 1)); % 左通道
            app.myRecording(:, 2) = filter(b, a, app.myRecording(:, 2)); % 右通道

            % 保存录音数据为WAV文件
            audiowrite('sound_stereo.wav', app.myRecording, app.fs);
            
            % 更新状态文本
            app.StatusLabel.Value = '录音完成';
            
            % 获取时间轴
            t = (1:app.duration * app.fs) / app.fs;
            
            % 绘制左通道的波形
            plot(app.RecordingAxes_left, t, app.myRecording(:, 1), 'g');  % 在左通道轴上绘制
            title(app.RecordingAxes_left, '左通道录音信号时域波形');
            xlabel(app.RecordingAxes_left, '时间/s');
            ylabel(app.RecordingAxes_left, '幅度');
            
            % 绘制右通道的波形
            plot(app.RecordingAxes_right, t, app.myRecording(:, 2), 'r');  % 在右通道轴上绘制
            title(app.RecordingAxes_right, '右通道录音信号时域波形');
            xlabel(app.RecordingAxes_right, '时间/s');
            ylabel(app.RecordingAxes_right, '幅度');


        end

        % Value changed function: StatusLabel
        function StatusLabelValueChanged(app, event)

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % 播放录音
            app.StatusLabel.Value = '正在播放';
            
            sound(app.myRecording, app.fs);  % 播放 app.myRecording 中的数据
 
            app.StatusLabel.Value = '播放完成';
        end

        % Button pushed function: cosButton
        function cosFunc(app, event)
            % 生成时间向量
            t = linspace(app.t_start, app.t_end, 1000);

            % 计算正弦波数据
            x = app.amp * cos(2 * pi * app.freq * t);
            
            % 清空当前图形并绘制新波形
            %clearaxes(app.WaveAxes); % 关键步骤：清空坐标区
            plot(app.WaveAxes, t, x, 'LineWidth', 2);
            
            % 更新图形属性
            xlabel(app.WaveAxes, 'Time (s)');
            ylabel(app.WaveAxes, 'Amplitude');
            title(app.WaveAxes, 'Cos Wave');
            grid(app.WaveAxes, 'on');
        end

        % Button pushed function: sinButton
        function sinPushed(app, event)
            % 生成时间向量
            t = linspace(app.t_start, app.t_end, 1000);

            % 计算正弦波数据
            x = app.amp * sin(2 * pi * app.freq * t);
            
            % 清空当前图形并绘制新波形
            %clearaxes(app.WaveAxes); % 关键步骤：清空坐标区
            plot(app.WaveAxes, t, x, 'LineWidth', 2);
            
            % 更新图形属性
            xlabel(app.WaveAxes, 'Time (s)');
            ylabel(app.WaveAxes, 'Amplitude');
            title(app.WaveAxes, 'Sine Wave');
            grid(app.WaveAxes, 'on');
        end

        % Button pushed function: square1
        function square1Pushed(app, event)
            
            % 生成时间向量
            t = linspace(app.t_start, app.t_end, 1000);
            
            % 计算方波数据
            x = app.amp * square(2 * pi * app.freq * t);
            
            % 绘制方波
            plot(app.WaveAxes, t, x, 'LineWidth', 2);
            
            % 更新图形属性
            xlabel(app.WaveAxes, 'Time (s)');
            ylabel(app.WaveAxes, 'Amplitude');
            title(app.WaveAxes, 'Square Wave');
            grid(app.WaveAxes, 'on');
        end

        % Button pushed function: step
        function stepButtonPushed(app, event)
            t = linspace(app.t_start, app.t_end, 1000);
            
            % 计算阶跃信号数据
            x = app.amp * stepfun(t, app.t_start);
            
            % 绘制阶跃信号
            plot(app.WaveAxes, t, x, 'LineWidth', 2);
            
            % 更新图形属性
            xlabel(app.WaveAxes, 'Time (s)');
            ylabel(app.WaveAxes, 'Amplitude');
            title(app.WaveAxes, 'Step Signal');
            grid(app.WaveAxes, 'on');
        end

        % Button pushed function: chirpButton
        function ChirpFunc(app, event)
            f0 = 1;
            % 生成时间向量
            t = linspace(app.t_start, app.t_end, 1000);
            
            % 计算 chirp 信号数据
            x = chirp(t, f0, app.t_end, 2*f0); % 线性调频，结束频率为初始频率的两倍
            
            % 绘制 chirp 信号
            plot(app.WaveAxes, t, x, 'LineWidth', 2);
            
            % 更新图形属性
            xlabel(app.WaveAxes, 'Time (s)');
            ylabel(app.WaveAxes, 'Amplitude');
            title(app.WaveAxes, 'Chirp Signal');
            grid(app.WaveAxes, 'on');
        end

        % Button pushed function: add
        function addFunc(app, event)
            % 检查是否已有录音数据
            if isempty(app.myRecording)
                msgbox('请先录制音频信号！');
                return;
            end
            
            % 获取左右声道数据
            leftChannel = app.myRecording(:, 1);
            rightChannel = app.myRecording(:, 2);
            
            % 将两个声道的数据相加
            combinedSignal = leftChannel + rightChannel;
            
            % 获取时间轴
            t = (1:length(combinedSignal)) / app.fs;
            
            % 绘制相加后的波形
            plot(app.WaveAxes, t, combinedSignal, 'm', 'LineWidth', 2);  % 使用品红色绘制
            title(app.WaveAxes, '左右声道相加后的波形');
            xlabel(app.WaveAxes, '时间/s');
            ylabel(app.WaveAxes, '幅度');
            grid(app.WaveAxes, 'on');
            
            % 可选：更新状态标签
            app.StatusLabel.Value = '左右声道已相加并绘制';  
        end

        % Button pushed function: multiple
        function multipleButtonPushed(app, event)
            % 检查是否已有录音数据
            if isempty(app.myRecording)
                msgbox('请先录制音频信号！');
                return;
            end
            
            % 获取左右声道数据
            leftChannel = app.myRecording(:, 1);
            rightChannel = app.myRecording(:, 2);
            
            % 将两个声道的数据相乘
            % 注意：音频信号通常是 int16 或其他整数类型，需要转换为 double 进行运算
            multipliedSignal = double(leftChannel) .* double(rightChannel);
            
            % 可选：将结果缩放回原始数据的范围
            % 例如，如果原始数据是 int16，最大值为 32767
            % multipliedSignal = (multipliedSignal / max(abs(multipliedSignal))) * 32767;
            
            % 获取时间轴
            t = (1:length(multipliedSignal)) / app.fs;
            
            % 绘制相乘后的波形
            plot(app.WaveAxes, t, multipliedSignal, 'm', 'LineWidth', 2);  % 使用品红色绘制
            title(app.WaveAxes, '左右声道相乘后的波形');
            xlabel(app.WaveAxes, '时间/s');
            ylabel(app.WaveAxes, '幅度');
            grid(app.WaveAxes, 'on');
            
            % 可选：更新状态标签
            app.StatusLabel.Value = '左右声道已相乘并绘制';
        end

        % Button pushed function: mean
        function meanButtonPushed(app, event)
            % 检查是否有录音数据
            if isempty(app.myRecording)
                msgbox('请先录制音频信号！');
                return;
            end
            
            % 获取左右声道数据
            leftChannel = app.myRecording(:, 1);
            rightChannel = app.myRecording(:, 2);
            
            % 计算均值
            averageSignal = (leftChannel + rightChannel) / 2;
            
            % 获取时间轴
            t = (1:length(averageSignal)) / app.fs;
            
            % 绘制均值波形
            plot(app.WaveAxes, t, averageSignal, 'm', 'LineWidth', 2);  % 使用品红色绘制
            title(app.WaveAxes, '左右声道均值波形');
            xlabel(app.WaveAxes, '时间/s');
            ylabel(app.WaveAxes, '幅度');
            grid(app.WaveAxes, 'on');
            
            % 更新状态标签
            app.StatusLabel.Value = '已计算并显示左右声道均值';
        end

        % Button pushed function: convolution
        function convolutionButtonPushed(app, event)
            % 检查是否有录音数据
            if isempty(app.myRecording)
                msgbox('请先录制音频信号！');
                return;
            end
            
            % 获取左右声道数据
            leftChannel = app.myRecording(:, 1);
            rightChannel = app.myRecording(:, 2);
            
            % 计算卷积
            convolutionSignal = conv(leftChannel, rightChannel);
            
            % 获取时间轴（卷积后信号长度为 L1 + L2 -1）
            t_conv = (1:length(convolutionSignal)) / app.fs;
            
            % 绘制卷积波形
            plot(app.WaveAxes, t_conv, convolutionSignal, 'c', 'LineWidth', 2);  % 使用青色绘制
            title(app.WaveAxes, '左右声道卷积波形');
            xlabel(app.WaveAxes, '时间/s');
            ylabel(app.WaveAxes, '幅度');
            grid(app.WaveAxes, 'on');
            
            % 更新状态标签
            app.StatusLabel.Value = '已计算并显示左右声道卷积波形';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RecordingAxes_right = uiaxes(app.UIFigure);
            app.RecordingAxes_right.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.RecordingAxes_right.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.RecordingAxes_left = uiaxes(app.UIFigure);
            app.RecordingAxes_left.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.RecordingAxes_left.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.WaveAxes = uiaxes(app.UIFigure);
            title(app.WaveAxes, 'Title')
            xlabel(app.WaveAxes, 'X')
            ylabel(app.WaveAxes, 'Y')
            zlabel(app.WaveAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '声音处理';
            app.signalPanel = uipanel(app.UIFigure);
            app.signalPanel.Title = 'signal';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = '开始';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '播放录音';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '当前状态：';
            app.StatusLabel = uitextarea(app.UIFigure);
            app.StatusLabel.ValueChangedFcn = createCallbackFcn(app, @StatusLabelValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '左通道';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '右通道';
            app.sinButton = uibutton(app.UIFigure, 'push');
            app.sinButton.ButtonPushedFcn = createCallbackFcn(app, @sinPushed, true);
            app.sinButton.Text = 'sin信号';
            app.cosButton = uibutton(app.UIFigure, 'push');
            app.cosButton.ButtonPushedFcn = createCallbackFcn(app, @cosFunc, true);
            app.cosButton.Text = 'cos信号';
            app.square1 = uibutton(app.UIFigure, 'push');
            app.square1.ButtonPushedFcn = createCallbackFcn(app, @square1Pushed, true);
            app.square1.Text = '方波';
            app.step = uibutton(app.UIFigure, 'push');
            app.step.ButtonPushedFcn = createCallbackFcn(app, @stepButtonPushed, true);
            app.step.Text = '阶跃';
            app.chirpButton = uibutton(app.UIFigure, 'push');
            app.chirpButton.ButtonPushedFcn = createCallbackFcn(app, @ChirpFunc, true);
            app.chirpButton.Text = 'chirp';
            app.add = uibutton(app.UIFigure, 'push');
            app.add.ButtonPushedFcn = createCallbackFcn(app, @addFunc, true);
            app.add.Text = '加法';
            app.multiple = uibutton(app.UIFigure, 'push');
            app.multiple.ButtonPushedFcn = createCallbackFcn(app, @multipleButtonPushed, true);
            app.multiple.Text = '乘法';
            app.mean = uibutton(app.UIFigure, 'push');
            app.mean.ButtonPushedFcn = createCallbackFcn(app, @meanButtonPushed, true);
            app.mean.Text = '均值';
            app.convolution = uibutton(app.UIFigure, 'push');
            app.convolution.ButtonPushedFcn = createCallbackFcn(app, @convolutionButtonPushed, true);
            app.convolution.Text = '卷积';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'信号分析仪'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end