classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        JisuanAnalyse     matlab.ui.container.Panel
        TextArea_2        matlab.ui.control.TextArea
        Label_10          matlab.ui.control.Label
        Library           matlab.ui.control.TextArea
        MATLABLabel       matlab.ui.control.Label
        Self              matlab.ui.control.TextArea
        Label_9           matlab.ui.control.Label
        Panel_6           matlab.ui.container.Panel
        PeakToPeak_       matlab.ui.control.Button
        Peak_             matlab.ui.control.Button
        RMSButton_        matlab.ui.control.Button
        MeanSquareValue_  matlab.ui.control.Button
        Mean_             matlab.ui.control.Button
        Variance_         matlab.ui.control.Button
        Period_           matlab.ui.control.Button
        MATLABPanel       matlab.ui.container.Panel
        Full              matlab.ui.control.Button
        PeakToPeak        matlab.ui.control.Button
        Peak              matlab.ui.control.Button
        RMSButton         matlab.ui.control.Button
        MeanSquareValue   matlab.ui.control.Button
        Mean              matlab.ui.control.Button
        Variance          matlab.ui.control.Button
        Period            matlab.ui.control.Button
        SignalAnalyse     matlab.ui.container.Panel
        Interpret         matlab.ui.control.Button
        Button_5          matlab.ui.control.Button
        ProcessingMethod  matlab.ui.control.DropDown
        Label_7           matlab.ui.control.Label
        Signal2           matlab.ui.control.DropDown
        Label_6           matlab.ui.control.Label
        Signal1           matlab.ui.control.DropDown
        Label_5           matlab.ui.control.Label
        UIAxes3           matlab.ui.control.UIAxes
        VoiceCollect      matlab.ui.container.Panel
        PlayButton        matlab.ui.control.Button
        StopButton        matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        StatusShow        matlab.ui.control.EditField
        Label_4           matlab.ui.control.Label
        VoiceAxes         matlab.ui.control.UIAxes
        SignalShow        matlab.ui.container.Panel
        TextArea          matlab.ui.control.TextArea
        StatusShow_2      matlab.ui.control.TextArea
        Label_8           matlab.ui.control.Label
        Input_2           matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        Input_1           matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        ClearSignal       matlab.ui.control.Button
        DropDown          matlab.ui.control.DropDown
        Label             matlab.ui.control.Label
        SignalAxes        matlab.ui.control.UIAxes
        Headline          matlab.ui.control.TextArea
    end

    
    properties (Access = private)
                Property % Description
                Fs = 44100; % 采样率
                nChannels = 2; % 双声道
                fs = 16000;               % 采样频率
                nBits = 8;                % 采样位数
                duration = 5;             % 录音时长
                y                         % 录音对象
                myRecording               % 录音数据
                freq = 1;
                amp = 1;
                t_start = 0;
                t_end = 1;
                isRecording = false; % 标记录音状态
                signalType = ''; % 用于存储选择的信号类型
                frequency = 0; % 用于存储频率值：task2
                amplitude = 0; % 用于存储幅度值：task2
                frequency1 = 1 % 默认频率值：task3
                amplitude1 = 1 % 默认幅度值：task3
                frequency2 = 1 % 默认频率值：task3
                amplitude2 = 1 % 默认幅度值：task3
                signalType1= ''% 用于存储选择的信号类型
                signalType2= ''% 用于存储选择的信号类型
                processingMethod
                pinlv = 1000
                t1  % 时间向量，持续时间为 1 秒
                signal_
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                app.isRecording = true;
                app.StatusShow.Value = '请等待录音完成，录音时间5s';
                
                % 创建录音对象并开始录制
                app.y = audiorecorder(app.fs, app.nBits, 2);
                record(app.y);  % 使用非阻塞方式开始录音
                
                % 开始计时并监听停止信号
                tic;
                while app.isRecording && toc < app.duration
                    pause(0.1); % 等待并检查是否需要停止
                end
                
                if app.isRecording
                    stop(app.y); % 如果循环结束是因为超时，则停止录音
                    app.isRecording = false;
                end
                
                % 获取录音数据
                app.myRecording = getaudiodata(app.y);
                
                % 对音频数据进行滤波
                [b, a] = butter(4, 4000/(app.fs/2), 'low');
                app.myRecording(:, 1) = filter(b, a, app.myRecording(:, 1)); % 左通道
                
                % 保存录音数据为WAV文件
                audiowrite('sound_stereo.wav', app.myRecording, app.fs);
                
                % 更新状态文本
                app.StatusShow.Value = '录音完成';
                
                % 绘制通道的波形
                t = (0:length(app.myRecording)-1)/app.fs;
                plot(app.VoiceAxes, t, app.myRecording(:, 1), 'g');  
                title(app.VoiceAxes, '录音信号时域波形');
                xlabel(app.VoiceAxes, '时间/s');
                ylabel(app.VoiceAxes, '幅度');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             %    % 停止录音
                stop(app.y); 
                
                % 获取录音数据
                app.myRecording = getaudiodata(app.y);
                
                % 应用低通滤波器
                [b, a] = butter(4, 4000/(app.fs/2), 'low');
                app.myRecording(:, 1) = filter(b, a, app.myRecording(:, 1)); % 左通道
                
                % 保存录音数据为WAV文件
                audiowrite('sound_stereo.wav', app.myRecording, app.fs);
                
                % 更新状态文本
                app.StatusShow.Value = '录音提前完成';
                
                % 绘制录音信号的时域波形
                t = (0:length(app.myRecording)-1)/app.fs;
                plot(app.VoiceAxes, t, app.myRecording(:, 1), 'g');  
                title(app.VoiceAxes, '录音信号时域波形');
                xlabel(app.VoiceAxes, '时间/s');
                ylabel(app.VoiceAxes, '幅度');

                app.StatusShow.Value = '没有正在进行的录音';
 
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
                app.StatusShow.Value = '正在播放';
                
                sound(app.myRecording, app.fs);  % 播放 app.myRecording 中的数据
     
                app.StatusShow.Value = '播放完成';
        end

        % Drop down opening function: DropDown
        function DropDownOpening(app, event)
                % % 获取选择的信号类型
                % app.signalType = app.DropDown.Value;
                % 
                % % 获取频率和幅度值
                % app.frequency = str2double(app.Input_1.Value);
                % app.amplitude = str2double(app.Input_2.Value);
                % t = linspace(0, 1, 1000); % 时间向量
                % value = app.DropDown.Value;
                % 
                % switch value
                %     case '正弦信号'
                %         signal = app.amplitude * sin(2*pi*app.frequency*t);
                %         plot(app.SignalAxes, t, signal);
                %         title(app.SignalAxes, ['信号类型: ' app.signalType]);
                %         xlabel(app.SignalAxes, '时间/s');
                %         ylabel(app.SignalAxes, '幅度');
                %     case '余弦信号'
                %         signal = app.amplitude * cos(2*pi*app.frequency*t);
                %     case '方波信号'
                %         signal = app.amplitude * square(2*pi*app.frequency*t);
                %     case '阶跃信号'
                %         signal = app.amplitude * stepfun(t - 0.5);
                %     case 'chirp信号'
                %         signal = app.amplitude * chirp(t, 0, 1, app.frequency);
                % end
                % % 绘制信号
                % plot(app.SignalAxes, t, signal);
                % title(app.SignalAxes, ['信号类型: ' app.signalType]);
                % xlabel(app.SignalAxes, '时间/s');
                % ylabel(app.SignalAxes, '幅度');
                % 
                % % 更新状态文本
                %app.StatusShow.Value = ['信号类型: ' app.signalType];

        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
                value = app.DropDown.Value;
                % 获取选择的信号类型
                app.signalType = app.DropDown.Value;
                
                % 获取频率和幅度值
                app.frequency = app.Input_1.Value;
                app.amplitude = app.Input_2.Value;
                
                % 确保频率和幅度值是有效的数字
                if isnan(app.frequency) || isnan(app.amplitude)
                    app.StatusShow_2.Value = '请输入有效的频率和幅度';
                    return;
                end
                
                t = linspace(0, 1, 1000); % 时间向量
                
                switch app.signalType
                    case '正弦信号'
                        signal = app.amplitude * sin(2*pi*app.frequency*t);
                    case '余弦信号'
                        signal = app.amplitude * cos(2*pi*app.frequency*t);
                    case '方波信号'
                        signal = app.amplitude * square(2*pi*app.frequency*t);
                    case '阶跃信号'
                        signal = app.amplitude * stepfun(t - 0.5, 0); % 注意：stepfun需要第二个参数指定步长起点
                    case 'chirp信号'
                        signal = app.amplitude * chirp(t, 0, 1, app.frequency);
                end

                % 绘制信号
                cla(app.SignalAxes); % 清除之前的绘图
                plot(app.SignalAxes, t, signal);
                title(app.SignalAxes, ['信号类型: ' app.signalType]);
                xlabel(app.SignalAxes, '时间/s');
                ylabel(app.SignalAxes, '幅度');

                % 更新状态文本
                app.StatusShow_2.Value = ['当前: ' app.signalType];          
        end

        % Button pushed function: ClearSignal
        function ClearSignalButtonPushed(app, event)
                cla(app.SignalAxes); % 清除之前的绘图
        end

        % Value changed function: Signal1
        function Signal1ValueChanged(app, event)
            app.signalType1 = app.Signal1.Value;
            %app.signalType1 = app.Signal1.Value;
        end

        % Value changed function: Signal2
        function Signal2ValueChanged(app, event)
            app.signalType2 = app.Signal2.Value;
            %app.signalType2 = app.Signal2.Value;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
        
            % 获取频率和幅度值
            app.frequency1 = 10;
            app.amplitude1 = 5;
            app.frequency2 = 10;
            app.amplitude2 = 5;
            
            t = linspace(0, 1, 1000); % 时间向量
            
            app.signalType1 = app.Signal1.Value;
            app.processingMethod = app.Signal2.Value;
            app.processingMethod = app.ProcessingMethod.Value;

            % 生成信号1
            switch app.signalType1
                case '正弦信号'
                    signal1 = app.amplitude1 * sin(2*pi*app.frequency1*t);
                case '余弦信号'
                    signal1 = app.amplitude1 * cos(2*pi*app.frequency1*t);
                case '方波信号'
                    signal1 = app.amplitude1 * square(2*pi*app.frequency1*t);
                case '阶跃信号'
                    signal1 = app.amplitude1 * stepfun(t - 0.5);
                case 'chirp信号'
                    signal1 = app.amplitude1 * chirp(t, 0, 1, app.frequency1);
            end
            
            % 生成信号2
            switch app.signalType2
                case '正弦信号'
                    signal2 = app.amplitude2 * sin(2*pi*app.frequency2*t);
                case '余弦信号'
                    signal2 = app.amplitude2 * cos(2*pi*app.frequency2*t);
                case '方波信号'
                    signal2 = app.amplitude2 * square(2*pi*app.frequency2*t);
                case '阶跃信号'
                    signal2 = app.amplitude2 * stepfun(t - 0.5);
                case 'chirp信号'
                    signal2 = app.amplitude2 * chirp(t, 0, 1, app.frequency2);
            end
            
            % 根据处理方式进行计算
            switch app.processingMethod
                case '加法'
                    result = signal1 + signal2;
                    t_result = t; % 使用原始的时间向量
                case '乘法'
                    result = signal1 .* signal2;
                    t_result = t; % 使用原始的时间向量
                case '均值'
                    result = (signal1 + signal2) / 2;
                    t_result = t; % 使用原始的时间向量
                case '卷积'
                    result = conv(signal1, signal2);
                    t_result = linspace(0, 2, length(result)); % 卷积后的信号长度加倍
            end
            
            % 清除之前的绘图
            cla(app.UIAxes3);
            
            % 绘制结果
            plot(app.UIAxes3, t_result, result);
            title(app.UIAxes3, ['处理方式: ' app.processingMethod]);
            xlabel(app.UIAxes3, '时间/s');
            ylabel(app.UIAxes3, '幅度');
            
            % 更新状态文本
            % app.StatusShow.Text = ['当前处理方式: ' app.processingMethod];

        end

        % Button pushed function: Interpret
        function InterpretButtonPushed(app, event)
            
        end

        % Button pushed function: Period
        function PeriodButtonPushed(app, event)
            % 生成信号
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            % 找到正到负过零点的样本位置
            zero_crossings = find(diff(sign(app.signal_)) < 0);
            
            if length(zero_crossings) >= 2
                % 计算样本数差并转换为时间间隔
                sample_diff = mean(diff(zero_crossings));
                time_interval = sample_diff * (1/app.pinlv); % 乘以采样间隔时间
                period = time_interval; % 直接得到周期
            else
                period = NaN; % 不足两个过零点时无法计算
            end
            
            app.Library.Value = ['周期值: ' num2str(period)];
        end

        % Button pushed function: Peak
        function PeakButtonPushed(app, event)
            app.t1=0:1/app.pinlv:1-1/app.pinlv;
            app.signal_=5*sin(2*pi*10*app.t1);
            peak = max(abs(app.signal_));
            app.Library.Value = ['峰值: ' num2str(peak)];
        end

        % Button pushed function: Variance
        function VarianceButtonPushed(app, event)
            app.t1=0:1/app.pinlv:1-1/app.pinlv;
            app.signal_=5*sin(2*pi*10*app.t1);
            variance = var(app.signal_);
            app.Library.Value = ['方差值: ' num2str(variance)];
        end

        % Button pushed function: Mean
        function MeanButtonPushed(app, event)
            app.t1=0:1/app.pinlv:1-1/app.pinlv;
            app.signal_=5*sin(2*pi*10*app.t1);
            meanValue = mean(app.signal_);
            app.Library.Value = ['均值: ' num2str(meanValue)];
        end

        % Button pushed function: MeanSquareValue
        function MeanSquareValueButtonPushed(app, event)
            app.t1=0:1/app.pinlv:1-1/app.pinlv;
            app.signal_=5*sin(2*pi*10*app.t1);
            meanSquareValue = mean(app.signal_.^2);
            app.Library.Value = ['均方值: ' num2str(meanSquareValue)];
        end

        % Button pushed function: RMSButton
        function RMSButtonPushed(app, event)
            app.t1=0:1/app.pinlv:1-1/app.pinlv;
            app.signal_=5*sin(2*pi*10*app.t1);
            rmsValue = sqrt(mean(app.signal_.^2));
            app.Library.Value = ['RMS值: ' num2str(rmsValue)];
        end

        % Button pushed function: PeakToPeak
        function PeakToPeakButtonPushed(app, event)
            app.t1=0:1/app.pinlv:1-1/app.pinlv;
            app.signal_=5*sin(2*pi*10*app.t1);
            peakToPeak = max(app.signal_) - min(app.signal_);
            app.Library.Value = ['峰峰值: ' num2str(peakToPeak)];
        end

        % Button pushed function: Full
        function FullButtonPushed(app, event)
            app.t1=0:1/app.pinlv:1-1/app.pinlv;
            app.signal_=5*sin(2*pi*10*app.t1);
            % 计算信号的周期
            freq_ = 1 / mean(diff(find(diff(sign(app.signal_)) < 0))); % 找到过零点并计算平均周期
            period = 1 / freq_;  
            peak = max(abs(app.signal_));
            variance = var(app.signal_);
            meanValue = mean(app.signal_);
            meanSquareValue = mean(app.signal_.^2);
            rmsValue = sqrt(mean(app.signal_.^2));
            peakToPeak = max(app.signal_) - min(app.signal_);
            % 使用 sprintf 格式化输出字符串
            outputText = sprintf(['频率: %.2f Hz\n周期: %.2f s\n峰值: %.2f\n方差: %.2f\n'...
                '均值: %.2f\n均方值: %.2f\nRMS值: %.2f\n峰峰值: %.2f'], ...
                freq_, period, peak, variance, meanValue, meanSquareValue, rmsValue, peakToPeak);
            app.Library.Value = outputText;
        end

        % Button pushed function: Period_
        function Period_ButtonPushed(app, event)

            % 生成信号
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            % FFT分析
            L = length(app.signal_);
            Y = fft(app.signal_);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = app.pinlv*(0:(L/2))/L;
            
            % 找到主频峰值
            [~, idx] = max(P1);
            freq1 = f(idx);
            period = 1/freq1;
            
            app.Self.Value = ['周期值: ' num2str(period)];
        end

        % Button pushed function: Variance_
        function Variance_ButtonPushed(app, event)

            % 生成信号
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            N = length(app.signal_);
            sum_x = sum(app.signal_);
            sum_x2 = sum(app.signal_.^2);
            variance = (sum_x2 - sum_x^2/N) / (N-1); % 样本方差
            
            app.Self.Value = ['方差值: ' num2str(variance)];

        end

        % Button pushed function: Mean_
        function Mean_ButtonPushed(app, event)
            % 生成信号
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            % 向量化计算均值
            sumValue = sum(app.signal_);       % 直接求和
            numElements = length(app.signal_);
            meanValue = sumValue / numElements;
            
            % 更新UI显示
            app.Self.Value = ['均值: ' num2str(meanValue)];
        end

        % Button pushed function: MeanSquareValue_
        function MeanSquareValue_ButtonPushed(app, event)
            % 生成信号
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            % 计算信号平方的积分
            signal_squared = app.signal_.^2;
            total_time = app.t1(end) - app.t1(1); % 信号总时长（此处为1秒）
            integral_value = trapz(app.t1, signal_squared); % 梯形法积分
            
            % 均方值 = 积分值 / 总时长
            meanSquareValue = integral_value / total_time;
            
            app.Self.Value = ['均方值: ' num2str(meanSquareValue)];
        end

        % Button pushed function: RMSButton_
        function RMSButton_Pushed(app, event)
            % 生成信号
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            % 计算信号平方的数值积分
            squared_signal = app.signal_.^2;
            total_time = app.t1(end) - app.t1(1); % 信号总时长（此处为1秒）
            integral_value = trapz(app.t1, squared_signal); % 梯形法积分
            
            % 计算RMS值
            mean_square = integral_value / total_time; % 均方值
            rmsValue = sqrt(mean_square);
            
            app.Self.Value = ['RMS值: ' num2str(rmsValue)];
        end

        % Button pushed function: Peak_
        function Peak_ButtonPushed(app, event)
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            % 检测所有局部峰值并取最大绝对值
            [~, locs] = findpeaks(abs(app.signal_));
            if ~isempty(locs)
                peakValue = max(app.signal_(locs));
            else
                peakValue = max(abs(app.signal_)); % 无局部峰时回退全局最大
            end
            
            app.Self.Value = ['峰值: ' num2str(peakValue)];
        end

        % Button pushed function: PeakToPeak_
        function PeakToPeak_ButtonPushed(app, event)
            % 生成信号
            app.t1 = 0:1/app.pinlv:1-1/app.pinlv;
            app.signal_ = 5*sin(2*pi*10*app.t1);
            
            % 直接调用max和min函数计算峰峰值
            maxVal = max(app.signal_);
            minVal = min(app.signal_);
            peakToPeak = maxVal - minVal;
            
            app.Self.Value = ['峰峰值: ' num2str(peakToPeak)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Headline = uitextarea(app.UIFigure);
            app.Headline.Value = {'MATLAB信号分析仪'};
            app.SignalShow = uipanel(app.UIFigure);
            app.SignalShow.Title = '典型信号演示';
            app.SignalAxes = uiaxes(app.SignalShow);
            title(app.SignalAxes, 'Title')
            xlabel(app.SignalAxes, 'X')
            ylabel(app.SignalAxes, 'Y')
            zlabel(app.SignalAxes, 'Z')
            app.Label = uilabel(app.SignalShow);
            app.Label.Text = '信号类型';
            app.DropDown = uidropdown(app.SignalShow);
            app.DropDown.Items = {'正弦信号', '余弦信号', '方波信号', '阶跃信号', 'chirp信号'};
            app.DropDown.DropDownOpeningFcn = createCallbackFcn(app, @DropDownOpening, true);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '正弦信号';
            app.ClearSignal = uibutton(app.SignalShow, 'push');
            app.ClearSignal.ButtonPushedFcn = createCallbackFcn(app, @ClearSignalButtonPushed, true);
            app.ClearSignal.Text = '消除信号';
            app.Label_2 = uilabel(app.SignalShow);
            app.Label_2.Text = '频率';
            app.Input_1 = uieditfield(app.SignalShow, 'numeric');
            app.Label_3 = uilabel(app.SignalShow);
            app.Label_3.Text = '幅度';
            app.Input_2 = uieditfield(app.SignalShow, 'numeric');
            app.Label_8 = uilabel(app.SignalShow);
            app.Label_8.Text = '当前状态';
            app.StatusShow_2 = uitextarea(app.SignalShow);
            app.TextArea = uitextarea(app.SignalShow);
            app.TextArea.Value = {'请输入频率和幅度！'};
            app.VoiceCollect = uipanel(app.UIFigure);
            app.VoiceCollect.Title = '声音采集';
            app.VoiceAxes = uiaxes(app.VoiceCollect);
            title(app.VoiceAxes, 'Title')
            xlabel(app.VoiceAxes, 'X')
            ylabel(app.VoiceAxes, 'Y')
            zlabel(app.VoiceAxes, 'Z')
            app.Label_4 = uilabel(app.VoiceCollect);
            app.Label_4.Text = '当前状态：';
            app.StatusShow = uieditfield(app.VoiceCollect, 'text');
            app.StartButton = uibutton(app.VoiceCollect, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '开始采集';
            app.StopButton = uibutton(app.VoiceCollect, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '结束采集';
            app.PlayButton = uibutton(app.VoiceCollect, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = '播放音频';
            app.SignalAnalyse = uipanel(app.UIFigure);
            app.SignalAnalyse.Title = '信号运算';
            app.UIAxes3 = uiaxes(app.SignalAnalyse);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.Label_5 = uilabel(app.SignalAnalyse);
            app.Label_5.Text = '信号1';
            app.Signal1 = uidropdown(app.SignalAnalyse);
            app.Signal1.Items = {'正弦信号', '余弦信号', '方波信号', '阶跃信号', 'chirp信号'};
            app.Signal1.ValueChangedFcn = createCallbackFcn(app, @Signal1ValueChanged, true);
            app.Signal1.Value = '正弦信号';
            app.Label_6 = uilabel(app.SignalAnalyse);
            app.Label_6.Text = '信号2';
            app.Signal2 = uidropdown(app.SignalAnalyse);
            app.Signal2.Items = {'正弦信号', '余弦信号', '方波信号', '阶跃信号', 'chirp信号'};
            app.Signal2.ValueChangedFcn = createCallbackFcn(app, @Signal2ValueChanged, true);
            app.Signal2.Value = '正弦信号';
            app.Label_7 = uilabel(app.SignalAnalyse);
            app.Label_7.Text = '处理方式';
            app.ProcessingMethod = uidropdown(app.SignalAnalyse);
            app.ProcessingMethod.Items = {'加法', '乘法', '均值', '卷积'};
            app.ProcessingMethod.Value = '加法';
            app.Button_5 = uibutton(app.SignalAnalyse, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '执行处理方式';
            app.Interpret = uibutton(app.SignalAnalyse, 'push');
            app.Interpret.ButtonPushedFcn = createCallbackFcn(app, @InterpretButtonPushed, true);
            app.Interpret.Text = '卷积分析';
            app.JisuanAnalyse = uipanel(app.UIFigure);
            app.JisuanAnalyse.Title = '计算分析';
            app.MATLABPanel = uipanel(app.JisuanAnalyse);
            app.MATLABPanel.Title = 'MATLAB内置算法';
            app.Period = uibutton(app.MATLABPanel, 'push');
            app.Period.ButtonPushedFcn = createCallbackFcn(app, @PeriodButtonPushed, true);
            app.Period.Text = '周期计算';
            app.Variance = uibutton(app.MATLABPanel, 'push');
            app.Variance.ButtonPushedFcn = createCallbackFcn(app, @VarianceButtonPushed, true);
            app.Variance.Text = '方差计算';
            app.Mean = uibutton(app.MATLABPanel, 'push');
            app.Mean.ButtonPushedFcn = createCallbackFcn(app, @MeanButtonPushed, true);
            app.Mean.Text = '均值计算';
            app.MeanSquareValue = uibutton(app.MATLABPanel, 'push');
            app.MeanSquareValue.ButtonPushedFcn = createCallbackFcn(app, @MeanSquareValueButtonPushed, true);
            app.MeanSquareValue.Text = '均方值计算';
            app.RMSButton = uibutton(app.MATLABPanel, 'push');
            app.RMSButton.ButtonPushedFcn = createCallbackFcn(app, @RMSButtonPushed, true);
            app.RMSButton.Text = 'RMS计算';
            app.Peak = uibutton(app.MATLABPanel, 'push');
            app.Peak.ButtonPushedFcn = createCallbackFcn(app, @PeakButtonPushed, true);
            app.Peak.Text = '峰值计算';
            app.PeakToPeak = uibutton(app.MATLABPanel, 'push');
            app.PeakToPeak.ButtonPushedFcn = createCallbackFcn(app, @PeakToPeakButtonPushed, true);
            app.PeakToPeak.Text = '峰峰值计算';
            app.Full = uibutton(app.MATLABPanel, 'push');
            app.Full.ButtonPushedFcn = createCallbackFcn(app, @FullButtonPushed, true);
            app.Full.Text = '快速计算';
            app.Panel_6 = uipanel(app.JisuanAnalyse);
            app.Panel_6.Title = '自定义算法';
            app.Period_ = uibutton(app.Panel_6, 'push');
            app.Period_.ButtonPushedFcn = createCallbackFcn(app, @Period_ButtonPushed, true);
            app.Period_.Text = '周期计算';
            app.Variance_ = uibutton(app.Panel_6, 'push');
            app.Variance_.ButtonPushedFcn = createCallbackFcn(app, @Variance_ButtonPushed, true);
            app.Variance_.Text = '方差计算';
            app.Mean_ = uibutton(app.Panel_6, 'push');
            app.Mean_.ButtonPushedFcn = createCallbackFcn(app, @Mean_ButtonPushed, true);
            app.Mean_.Text = '均值计算';
            app.MeanSquareValue_ = uibutton(app.Panel_6, 'push');
            app.MeanSquareValue_.ButtonPushedFcn = createCallbackFcn(app, @MeanSquareValue_ButtonPushed, true);
            app.MeanSquareValue_.Text = '均方值计算';
            app.RMSButton_ = uibutton(app.Panel_6, 'push');
            app.RMSButton_.ButtonPushedFcn = createCallbackFcn(app, @RMSButton_Pushed, true);
            app.RMSButton_.Text = 'RMS计算';
            app.Peak_ = uibutton(app.Panel_6, 'push');
            app.Peak_.ButtonPushedFcn = createCallbackFcn(app, @Peak_ButtonPushed, true);
            app.Peak_.Text = '峰值计算';
            app.PeakToPeak_ = uibutton(app.Panel_6, 'push');
            app.PeakToPeak_.ButtonPushedFcn = createCallbackFcn(app, @PeakToPeak_ButtonPushed, true);
            app.PeakToPeak_.Text = '峰峰值计算';
            app.Label_9 = uilabel(app.JisuanAnalyse);
            app.Label_9.Text = '自定义算法结果分析：';
            app.Self = uitextarea(app.JisuanAnalyse);
            app.MATLABLabel = uilabel(app.JisuanAnalyse);
            app.MATLABLabel.Text = 'MATLAB内置结果分析：';
            app.Library = uitextarea(app.JisuanAnalyse);
            app.Label_10 = uilabel(app.JisuanAnalyse);
            app.Label_10.Text = '处理信号';
            app.TextArea_2 = uitextarea(app.JisuanAnalyse);
            app.TextArea_2.Value = {'5*sin(2*pi*1/100)'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end